classdef MEEGprepUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralInformationTab           matlab.ui.container.Tab
        ParticipanttypeDropDown         matlab.ui.control.DropDown
        ParticipanttypeDropDown_2Label  matlab.ui.control.Label
        OutputPathEditField             matlab.ui.control.EditField
        pathLabel_3                     matlab.ui.control.Label
        EEGLABPathEditField             matlab.ui.control.EditField
        pathLabel_2                     matlab.ui.control.Label
        DatasetFileEditField            matlab.ui.control.EditField
        filenameLabel                   matlab.ui.control.Label
        DatasetPathEditField            matlab.ui.control.EditField
        pathLabel                       matlab.ui.control.Label
        OutputPathButton                matlab.ui.control.Button
        LabelInfo                       matlab.ui.control.Label
        EEGLABPathButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DatasetFileButton               matlab.ui.control.Button
        DatasetPathButton               matlab.ui.control.Button
        ChannelsTab                     matlab.ui.container.Tab
        ResetButton                     matlab.ui.control.Button
        OriginCapsystemDropDown         matlab.ui.control.DropDown
        OriginCapsystemDropDownLabel    matlab.ui.control.Label
        ConvertButton                   matlab.ui.control.Button
        ConvertCapsystemtoDropDown      matlab.ui.control.DropDown
        ParticipanttypeDropDown_2Label_2  matlab.ui.control.Label
        UITableChannels                 matlab.ui.control.Table
        EventsTab                       matlab.ui.container.Tab
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        SelectdataDropDown              matlab.ui.control.DropDown
        SelectdataDropDownLabel         matlab.ui.control.Label
        UITableEvents                   matlab.ui.control.Table
        MethodsTab                      matlab.ui.container.Tab
        MethodsPanel                    matlab.ui.container.Panel
        PACCheckBox                     matlab.ui.control.CheckBox
        MICCheckBox                     matlab.ui.control.CheckBox
        MIMCheckBox                     matlab.ui.control.CheckBox
        TRDTFCheckBox                   matlab.ui.control.CheckBox
        DTFCheckBox                     matlab.ui.control.CheckBox
        TRPDCCheckBox                   matlab.ui.control.CheckBox
        PDCCheckBox                     matlab.ui.control.CheckBox
        wPLICheckBox                    matlab.ui.control.CheckBox
        TRGCCheckBox                    matlab.ui.control.CheckBox
        GCCheckBox                      matlab.ui.control.CheckBox
        iCOHCheckBox                    matlab.ui.control.CheckBox
        cCOHCheckBox                    matlab.ui.control.CheckBox
        aCOHCheckBox                    matlab.ui.control.CheckBox
        CSCheckBox                      matlab.ui.control.CheckBox
        Showrejectedsubjects            matlab.ui.control.Hyperlink
        ButtonCancel                    matlab.ui.control.Button
        ButtonRun                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        eegp_properties % Description
        rejected_subs % Description
        ref_sub % Description
        eegchannels % Description
        eegevents % Description
    end
    
    methods (Access = private)
        
        function SelectAll(app, cbx)
             if(cbx.Value)
                app.UITable.Data{:,1} = true(size(app.UITable.Data(:,1)));
            else
                app.UITable.Data{:,1} = false(size(app.UITable.Data(:,1)));
            end
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");            
            app.eegp_properties = get_properties();

        end

        % Button pushed function: DatasetPathButton
        function DatasetPathButtonPushed(app, event)
            % try
                folder = uigetdir("Select the Dataset path");
                folder = strrep(folder,'\','/');
                app.DatasetPathEditField.Value = folder;

                % Filling table
                participants_file = fullfile(folder,'participants.tsv');
                if(isfile(participants_file))
                    opts = detectImportOptions(participants_file, FileType="text");
                    participants = readtable(participants_file,opts);
                    cbxs = true(size(participants,1),1);
                    column = fieldnames(participants);
                    participants = [array2table(cbxs),participants];
                    subjects = dir(folder);
                    reject = [];
                    for i=1:size(participants,1)
                        pos = find(ismember({subjects.name},participants.participant_id(i)),1);
                        if(isempty(pos))
                            reject = [reject i];
                        end
                    end
                    participants(reject,:) = [];
                    app.UITable.Data = participants;
                    app.UITable.ColumnName = [{'Select'},column'];
                else
                    subjects = dir(folder);
                    subjects(ismember({subjects.name},{'.','..','derivatives','NIM_EEGXplorer','.git'})) = [];
                    subjects(~[subjects.isdir]) = [];
                    subs = {subjects.name}';
                    cbxs = true(size(subjects));
                    T = table(cbxs,subs);
                    app.UITable.Data = T;
                end
                if(isequal(app.GeneralInformationTab.Children(1).Tag,'CheckAll'))
                    delete(app.GeneralInformationTab.Children(1))
                end
                uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[51,287,30,30],Parent=app.GeneralInformationTab,...
                    ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                total = size(subjects,1);                
                selected = length(find(table2array(app.UITable.Data(:,1))));
                app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
            % catch
            %     app.DatasetPathEditField.Value = '';
            % end
            app.DatasetFileEditField.Value = '';
            app.Showrejectedsubjects.Enable = false;
            figure(app.UIFigure);
        end

        % Button pushed function: DatasetFileButton
        function DatasetFileButtonPushed(app, event)
            try
                if(isempty(app.DatasetPathEditField.Value))
                    msgbox('Select first the Dataset base path.','Info');
                    return;
                end
                root_path = app.DatasetPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.edf';'*.set';'*.mat';'*.plg';'*.eeg'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.DatasetPathEditField.Value;
                 
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end

                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name); 

                app.ref_sub.SubID = SubID;
                app.ref_sub.Path = root_path;
                app.ref_sub.File = ref_path;

                ref_path = strrep(ref_path,SubID,'SubID');
                app.DatasetFileEditField.Value = ref_path;     

                
                % Filling table
                subjects = dir(app.DatasetPathEditField.Value);
                subjects(ismember({subjects.name},{'.','..','derivatives','NIM_EEGXplorer','.git'})) = [];
                subjects(~[subjects.isdir]) = [];
                subs = {subjects.name}';
                reject = [];
                for i=1:length(subs)
                    sub = subs{i};
                    file = fullfile(app.DatasetPathEditField.Value,sub,strrep(ref_path,'SubID',sub));
                    if(~isfile(file))
                        reject = [reject i];
                    end
                end
                app.rejected_subs = subjects(reject);
                app.Showrejectedsubjects.Enable = false;
                if(~isempty(reject))
                    app.Showrejectedsubjects.Enable = true;
                    subs(reject) = [];
                    cbxs = true(size(subs));
                    [~,name,ext] = fileparts(app.DatasetFileEditField.Value);
                    files = {};
                    for i=1:length(subs)
                       files{i}= strcat(strrep(name,'SubID',subs{i}),ext);
                    end
                     T = table(cbxs,subs,files');
                    app.UITable.Data = T;
                    if(isequal(app.GeneralInformationTab.Children(1).Tag,'CheckAll'))
                        delete(app.GeneralInformationTab.Children(1))
                    end
                    uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[51,287,30,30],Parent=app.GeneralInformationTab,...
                        ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                    total = size(subs,1);
                    selected = length(find(table2array(app.UITable.Data(:,1))));
                    app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
                    ms = msgbox({'Some subjects where rejected from the analysis.', ...
                        'The EEGs file were not found.',...
                        'Please check the Dataset information.'},'Info');
                    waitfor(ms);
                end 
                %% Collectiing reference Channels
                [~,~,ext]  = fileparts(file_name);
                channels_file = fullfile(file_path,strrep(strrep(strrep(file_name,'SubID',subs{1}),'eeg','channels'),ext,'.tsv'));
                opts = detectImportOptions(channels_file, FileType="text");
                channels = readtable(channels_file,opts);
                column = channels.Properties.VariableNames;
                app.UITableChannels.Data =  channels;
                app.UITableChannels.ColumnName = [column'];
                app.eegchannels.converted = false;

                %% Collectiing reference events
                [~,~,ext]  = fileparts(file_name);
                events_file = fullfile(file_path,strrep(strrep(strrep(file_name,'SubID',subs{1}),'eeg','events'),ext,'.tsv'));
                opts = detectImportOptions(events_file, FileType="text");
                events = readtable(events_file,opts);
                column = events.Properties.VariableNames;
                app.UITableEvents.Data =  events;
                app.UITableEvents.ColumnName = [column'];

            catch
                app.DatasetFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: EEGLABPathButton
        function EEGLABPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the EEGLAB root path");
                folder = strrep(folder,'\','/');
                app.EEGLABPathEditField.Value = folder;
            catch
                app.EEGLABPathEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ButtonRun
        function ButtonRunPushed(app, event)
            if(isempty(app.DatasetPathEditField.Value))
                msgbox({'The Dataset base path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.DatasetFileEditField.Value))
                msgbox({'The Dataset reference file can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end           
            if(isempty(app.EEGLABPathEditField.Value))
                msgbox({'The EEGLAB path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.OutputPathEditField.Value))
                msgbox({'The Output path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isequal(length(find(table2array(app.UITable.Data(:,1)))),0))
                msgbox({'You should select at least one subject to process.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end

            %% Channels
            

            %% Events            
            if(~isequal(app.SelectdataDropDown.Value,'Complete data') && isequal(app.FilterDropDown.Value,'--Select--'))
                msgbox({'You should select a correct Event filter.'},'Info');
                figure(app.UIFigure);
                return;
            end
            app.eegevents.Select = true;
            app.eegevents.Mode = app.SelectdataDropDown.Value;
            app.eegevents.Filter = app.FilterDropDown.Value;

            %% Methods
            methods = {};
            count = 0;
            for i=1:length(app.MethodsPanel.Children)
                method = app.MethodsPanel.Children(i);
                if(method.Value)                    
                    methods{end+1} = method.Text;
                else
                    count = count + 1;
                end
            end
            if(isequal(count,app.MethodsPanel.Children))
                msgbox({'You should select at least one Analysis method.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end           


            % Updating properties file
            app.eegp_properties.general.output_path = app.OutputPathEditField.Value;
            app.eegp_properties.general.eeglab_path = app.EEGLABPathEditField.Value;
            app.eegp_properties.dataset.root_path = app.DatasetPathEditField.Value;
            app.eegp_properties.dataset.ref_file = app.DatasetFileEditField.Value;
            indxs = find(table2array(app.UITable.Data(:,1)));
            subjects = table2array(app.UITable.Data(indxs,2));
            app.eegp_properties.dataset.participants = subjects;
            app.eegp_properties.dataset.channels = app.eegchannels;
            app.eegp_properties.dataset.events = app.eegevents;
            app.eegp_properties.dataset.methods = methods;
            % Saving property files
            saveJSON(app.eegp_properties,'app/properties.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'app/properties.json'));
            h.smartIndentContents
            h.save
            h.close
            
            % Processing Dataset
            processing_interface(app.eegp_properties);
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: OutputPathButton
        function OutputPathButtonPushed(app, event)
             try
                folder = uigetdir("Select the output path");
                folder = strrep(folder,'\','/');
                app.OutputPathEditField.Value = folder;
            catch
                app.OutputPathEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            if(isequal(app.ConvertCapsystemtoDropDown.Value,'--Select--') ...
                    || isequal(app.OriginCapsystemDropDown.Value,'--Select--'))
                msgbox({'Please select a correct Cap systems to transform.'},'Info');
                figure(app.UIFigure);
                return;
            else
                file_name = "";
                switch app.OriginCapsystemDropDown.Value
                    case 'BioSemi'
                        file_name = "BioSemi_to_";
                    case 'BrainProducts ActiCap'
                        file_name = "BrainProducts_ActiCap_to_";
                    case 'BrainProducts EasyCap'
                        file_name = "BrainProducts_EasyCap_to_";
                    case 'GSN HydroCel'
                        file_name = "GSN_HydroCel_to_";
                    case 'Neuroscan Quik-cap'
                        file_name = "Neuroscan_Quik-cap_to_";
                end
                switch app.ConvertCapsystemtoDropDown.Value
                    case '10-20_19'
                        file_name = strcat(file_name,'10-20_19.txt');
                    case '10-10_32'
                        file_name = strcat(file_name,'10-10_32.txt');
                    case '10-20_64'
                        file_name = strcat(file_name,'10-20_64.txt');
                end
                opts = detectImportOptions(fullfile(pwd,'functions','tools',file_name), FileType="text");
                table = table2struct(readtable(fullfile(pwd,'functions','tools',file_name),opts));
                tableChannels = table2struct(app.UITableChannels.Data);
                labels = {tableChannels.name}';
               
                labels_indx = [];
                for i=1:length(labels)
                    label = labels{i};
                    indx = find(ismember({table.BioSemi},label),1);
                    if(~isempty(indx))
                        tableChannels(i).name = table(indx).EasyCap;
                        labels_indx = [labels_indx i];
                    end
                end
                newtable = tableChannels(labels_indx,:);
                app.UITableChannels.Data =  struct2table(newtable);

                app.eegchannels.converted = true;
                app.eegchannels.from      = app.OriginCapsystemDropDown.Value;
                app.eegchannels.to        = app.ConvertCapsystemtoDropDown.Value;
            end
        end

        % Value changed function: SelectdataDropDown
        function SelectdataDropDownValueChanged(app, event)
            value = app.SelectdataDropDown.Value;
            if(isequal(value,'Complete data'))
                app.FilterDropDown.Enable = "off";
                app.FilterDropDown.Value = "--Select--";
                app.eegevents.Select = false;
            else
                app.FilterDropDown.Enable = "on";
                app.eegevents.Select = true;
                switch value
                    case 'Specific event'
                        app.FilterDropDown.Items = unique({table2struct(app.UITableEvents.Data).value});
                        app.FilterDropDown.Items{end+1} = '--Select--';
                        app.FilterDropDown.Value = '--Select--';
                    case 'Until event'
                        app.FilterDropDown.Items = unique({table2struct(app.UITableEvents.Data).value});
                        app.FilterDropDown.Items{end+1} = '--Select--';
                        app.FilterDropDown.Value = '--Select--';
                    case 'Before event'
                        app.FilterDropDown.Items = unique({table2struct(app.UITableEvents.Data).value});
                        app.FilterDropDown.Items{end+1} = '--Select--';
                        app.FilterDropDown.Value = '--Select--';
                    case 'Until time'
                        app.FilterDropDown.Items = unique({table2struct(app.UITableEvents.Data).onset});
                        app.FilterDropDown.Items{end+1} = '--Select--';
                        app.FilterDropDown.Value = '--Select--';
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             %% Reset reference Channels      
                app.OriginCapsystemDropDown.Value = '--Select--';
                app.ConvertCapsystemtoDropDown.Value = '--Select--';
                [path,name,ext]  = fileparts(app.ref_sub.File);
                channels_file = fullfile(app.ref_sub.Path,app.ref_sub.SubID,path,strcat(strrep(name,'eeg','channels'),'.tsv'));
                opts = detectImportOptions(channels_file, FileType="text");
                channels = readtable(channels_file,opts);
                column = channels.Properties.VariableNames;
                app.UITableChannels.Data =  channels;
                app.UITableChannels.ColumnName = [column'];
                app.eegchannels.converted = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'NIM EEG Xplorer';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ButtonRun = uibutton(app.UIFigure, 'push');
            app.ButtonRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunPushed, true);
            app.ButtonRun.Icon = fullfile(pathToMLAPP, 'images', 'run.jpg');
            app.ButtonRun.Text = 'Run';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.ButtonCancel.Text = 'Cancel';
            app.Showrejectedsubjects = uihyperlink(app.UIFigure);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralInformationTab = uitab(app.TabGroup);
            app.GeneralInformationTab.Title = 'General Information';
            app.DatasetPathButton = uibutton(app.GeneralInformationTab, 'push');
            app.DatasetPathButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetPathButtonPushed, true);
            app.DatasetPathButton.Tooltip = {'Root dataset path'};
            app.DatasetPathButton.Text = 'Select';
            app.DatasetFileButton = uibutton(app.GeneralInformationTab, 'push');
            app.DatasetFileButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetFileButtonPushed, true);
            app.DatasetFileButton.Tooltip = {'Reference file for one subject'};
            app.DatasetFileButton.Text = 'Select';
            app.UITable = uitable(app.GeneralInformationTab);
            app.UITable.ColumnName = {'Select'; 'Subjects'; 'File'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [true false false];
            app.UITable.Multiselect = 'off';
            app.EEGLABPathButton = uibutton(app.GeneralInformationTab, 'push');
            app.EEGLABPathButton.ButtonPushedFcn = createCallbackFcn(app, @EEGLABPathButtonPushed, true);
            app.EEGLABPathButton.Tooltip = {'EEGLAB root path'};
            app.EEGLABPathButton.Text = 'Select';
            app.LabelInfo = uilabel(app.GeneralInformationTab);
            app.LabelInfo.Text = '';
            app.OutputPathButton = uibutton(app.GeneralInformationTab, 'push');
            app.OutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPathButtonPushed, true);
            app.OutputPathButton.Tooltip = {'Output path'};
            app.OutputPathButton.Text = 'Select';
            app.pathLabel = uilabel(app.GeneralInformationTab);
            app.pathLabel.Text = 'Dataset path:';
            app.DatasetPathEditField = uieditfield(app.GeneralInformationTab, 'text');
            app.DatasetPathEditField.Editable = 'off';
            app.filenameLabel = uilabel(app.GeneralInformationTab);
            app.filenameLabel.Text = 'Reference file:';
            app.DatasetFileEditField = uieditfield(app.GeneralInformationTab, 'text');
            app.DatasetFileEditField.Editable = 'off';
            app.pathLabel_2 = uilabel(app.GeneralInformationTab);
            app.pathLabel_2.Text = 'EEGLAB path:';
            app.EEGLABPathEditField = uieditfield(app.GeneralInformationTab, 'text');
            app.EEGLABPathEditField.Editable = 'off';
            app.EEGLABPathEditField.Tooltip = {'Freesurfer Workspace'};
            app.pathLabel_3 = uilabel(app.GeneralInformationTab);
            app.pathLabel_3.Text = 'Output path:';
            app.OutputPathEditField = uieditfield(app.GeneralInformationTab, 'text');
            app.OutputPathEditField.Editable = 'off';
            app.OutputPathEditField.Tooltip = {'Freesurfer Workspace'};
            app.ParticipanttypeDropDown_2Label = uilabel(app.GeneralInformationTab);
            app.ParticipanttypeDropDown_2Label.Text = 'Participant type:';
            app.ParticipanttypeDropDown = uidropdown(app.GeneralInformationTab);
            app.ParticipanttypeDropDown.Items = {'All', 'Control', 'Pathological', 'Placebo'};
            app.ParticipanttypeDropDown.Value = 'All';
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.UITableChannels = uitable(app.ChannelsTab);
            app.UITableChannels.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableChannels.RowName = {};
            app.ParticipanttypeDropDown_2Label_2 = uilabel(app.ChannelsTab);
            app.ParticipanttypeDropDown_2Label_2.Text = 'Convert Cap system to:';
            app.ConvertCapsystemtoDropDown = uidropdown(app.ChannelsTab);
            app.ConvertCapsystemtoDropDown.Items = {'--Select--', '10-20 (19 channels)', '10-10 (32 channels)', '10-20 (64 channels)'};
            app.ConvertCapsystemtoDropDown.ItemsData = {'--Select--', '10-20_19', '10-10_32', '10-20_64'};
            app.ConvertCapsystemtoDropDown.Value = '--Select--';
            app.ConvertButton = uibutton(app.ChannelsTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.OriginCapsystemDropDownLabel = uilabel(app.ChannelsTab);
            app.OriginCapsystemDropDownLabel.Text = 'Origin Cap system:';
            app.OriginCapsystemDropDown = uidropdown(app.ChannelsTab);
            app.OriginCapsystemDropDown.Items = {'--Select--', 'BioSemi', 'BrainProducts ActiCap', 'BrainProducts EasyCap', 'GSN HydroCel', 'Neuroscan Quik-cap'};
            app.OriginCapsystemDropDown.Value = '--Select--';
            app.ResetButton = uibutton(app.ChannelsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EventsTab = uitab(app.TabGroup);
            app.EventsTab.Title = 'Events';
            app.UITableEvents = uitable(app.EventsTab);
            app.UITableEvents.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableEvents.RowName = {};
            app.SelectdataDropDownLabel = uilabel(app.EventsTab);
            app.SelectdataDropDownLabel.Text = 'Select data:';
            app.SelectdataDropDown = uidropdown(app.EventsTab);
            app.SelectdataDropDown.Items = {'Complete data', 'Specific event', 'Until event', 'Before event', 'Until time'};
            app.SelectdataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectdataDropDownValueChanged, true);
            app.SelectdataDropDown.Value = 'Complete data';
            app.FilterDropDownLabel = uilabel(app.EventsTab);
            app.FilterDropDownLabel.Enable = 'off';
            app.FilterDropDownLabel.Text = 'Filter:';
            app.FilterDropDown = uidropdown(app.EventsTab);
            app.FilterDropDown.Items = {'--Select--'};
            app.FilterDropDown.Enable = 'off';
            app.FilterDropDown.Value = '--Select--';
            app.MethodsTab = uitab(app.TabGroup);
            app.MethodsTab.Title = 'Methods';
            app.MethodsPanel = uipanel(app.MethodsTab);
            app.MethodsPanel.Title = 'Methods';
            app.CSCheckBox = uicheckbox(app.MethodsPanel);
            app.CSCheckBox.Text = 'CS';
            app.CSCheckBox.Value = true;
            app.aCOHCheckBox = uicheckbox(app.MethodsPanel);
            app.aCOHCheckBox.Text = 'aCOH';
            app.aCOHCheckBox.Value = true;
            app.cCOHCheckBox = uicheckbox(app.MethodsPanel);
            app.cCOHCheckBox.Text = 'cCOH';
            app.cCOHCheckBox.Value = true;
            app.iCOHCheckBox = uicheckbox(app.MethodsPanel);
            app.iCOHCheckBox.Text = 'iCOH';
            app.iCOHCheckBox.Value = true;
            app.GCCheckBox = uicheckbox(app.MethodsPanel);
            app.GCCheckBox.Text = 'GC';
            app.TRGCCheckBox = uicheckbox(app.MethodsPanel);
            app.TRGCCheckBox.Text = 'TRGC';
            app.wPLICheckBox = uicheckbox(app.MethodsPanel);
            app.wPLICheckBox.Text = 'wPLI';
            app.wPLICheckBox.Value = true;
            app.PDCCheckBox = uicheckbox(app.MethodsPanel);
            app.PDCCheckBox.Text = 'PDC';
            app.TRPDCCheckBox = uicheckbox(app.MethodsPanel);
            app.TRPDCCheckBox.Text = 'TRPDC';
            app.DTFCheckBox = uicheckbox(app.MethodsPanel);
            app.DTFCheckBox.Text = 'DTF';
            app.TRDTFCheckBox = uicheckbox(app.MethodsPanel);
            app.TRDTFCheckBox.Text = 'TRDTF';
            app.MIMCheckBox = uicheckbox(app.MethodsPanel);
            app.MIMCheckBox.Text = 'MIM';
            app.MICCheckBox = uicheckbox(app.MethodsPanel);
            app.MICCheckBox.Text = 'MIC';
            app.PACCheckBox = uicheckbox(app.MethodsPanel);
            app.PACCheckBox.Text = 'PAC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEEGprepUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end